classdef dip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AGIPToolsUIFigure              matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
        ImageMenu                      matlab.ui.container.Menu
        ColorsMenu                     matlab.ui.container.Menu
        RedMenu_2                      matlab.ui.container.Menu
        GreenMenu_2                    matlab.ui.container.Menu
        BlueMenu_2                     matlab.ui.container.Menu
        RGBToGrayMenu                  matlab.ui.container.Menu
        RedMenu                        matlab.ui.container.Menu
        GreenMenu                      matlab.ui.container.Menu
        BlueMenu                       matlab.ui.container.Menu
        AverageMenu                    matlab.ui.container.Menu
        RatioMenu                      matlab.ui.container.Menu
        GrayToBinaryMenu               matlab.ui.container.Menu
        ArithmeticMenu                 matlab.ui.container.Menu
        SumMenu                        matlab.ui.container.Menu
        MultiplicationMenu             matlab.ui.container.Menu
        DifferenceMenu                 matlab.ui.container.Menu
        DivisionMenu                   matlab.ui.container.Menu
        IntensityTransformationsMenu   matlab.ui.container.Menu
        LogarithmicMenu                matlab.ui.container.Menu
        InverseLogMenu                 matlab.ui.container.Menu
        PowerMenu                      matlab.ui.container.Menu
        RootMenu                       matlab.ui.container.Menu
        NegativeMenu                   matlab.ui.container.Menu
        ContrastStretchingMenu         matlab.ui.container.Menu
        BitPlaneSlicingMenu            matlab.ui.container.Menu
        HistogramEqualizationMenu      matlab.ui.container.Menu
        FiltersMenu                    matlab.ui.container.Menu
        UserFilterMenu                 matlab.ui.container.Menu
        LinearSpatialFilteringMenu     matlab.ui.container.Menu
        MeanMenu                       matlab.ui.container.Menu
        ArithmeticMeanMenu_2           matlab.ui.container.Menu
        GeometricMeanMenu              matlab.ui.container.Menu
        HarmonicMeanMenu               matlab.ui.container.Menu
        EdgeDetectionMenu              matlab.ui.container.Menu
        LaplacianMenu                  matlab.ui.container.Menu
        SimpleLaplacianMenu_3          matlab.ui.container.Menu
        VariantofLaplacianMenu_3       matlab.ui.container.Menu
        SobelMenu                      matlab.ui.container.Menu
        HorizontalMenu                 matlab.ui.container.Menu
        VerticalMenu                   matlab.ui.container.Menu
        LeftDiagonalMenu               matlab.ui.container.Menu
        RightDiagonalMenu              matlab.ui.container.Menu
        RobertMenu                     matlab.ui.container.Menu
        HorizontalMenu_2               matlab.ui.container.Menu
        VerticalMenu_2                 matlab.ui.container.Menu
        LeftDiagonalMenu_2             matlab.ui.container.Menu
        RightDiagonalMenu_2            matlab.ui.container.Menu
        SharpeningMenu                 matlab.ui.container.Menu
        LaplacianMenu_2                matlab.ui.container.Menu
        HorizontalMenu_3               matlab.ui.container.Menu
        VerticalMenu_3                 matlab.ui.container.Menu
        LeftDiagonalMenu_3             matlab.ui.container.Menu
        RightDiagonalMenu_3            matlab.ui.container.Menu
        NonlinearSpatialFilteringMenu  matlab.ui.container.Menu
        MinMenu                        matlab.ui.container.Menu
        MaxMenu                        matlab.ui.container.Menu
        MedianMenu                     matlab.ui.container.Menu
        MidpointMenu                   matlab.ui.container.Menu
        FrequencyDomainMenu_2          matlab.ui.container.Menu
        LogDFTMenu                     matlab.ui.container.Menu
        LowpassFiltersMenu_2           matlab.ui.container.Menu
        IdealMenu_3                    matlab.ui.container.Menu
        GaussianMenu_3                 matlab.ui.container.Menu
        ButterworthMenu_3              matlab.ui.container.Menu
        HighpassFiltersMenu_2          matlab.ui.container.Menu
        IdealMenu_4                    matlab.ui.container.Menu
        GaussianMenu_4                 matlab.ui.container.Menu
        ButterworthMenu_4              matlab.ui.container.Menu
        BandpassFiltersMenu            matlab.ui.container.Menu
        IdealMenu_5                    matlab.ui.container.Menu
        GaussianMenu_5                 matlab.ui.container.Menu
        ButterworthMenu_5              matlab.ui.container.Menu
        BandrejectFiltersMenu          matlab.ui.container.Menu
        IdealMenu_6                    matlab.ui.container.Menu
        GaussianMenu_6                 matlab.ui.container.Menu
        ButterworthMenu_6              matlab.ui.container.Menu
        NoiseMenu                      matlab.ui.container.Menu
        SaltandPepperMenu              matlab.ui.container.Menu
        UniformMenu                    matlab.ui.container.Menu
        GaussianMenu_7                 matlab.ui.container.Menu
        RayleighMenu                   matlab.ui.container.Menu
        ExponentialMenu                matlab.ui.container.Menu
        GammaMenu                      matlab.ui.container.Menu
        StatisticsMenu                 matlab.ui.container.Menu
        HistogramMenu                  matlab.ui.container.Menu
        OneHistogramMenu               matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        img % Current image
        original
    end

    methods (Access = private)
    
        function [] = browseFile(app)
            [path, user_canceled] = imgetfile();
            if user_canceled
                return
            end
            
            app.img = imread(path);
            imshow(app.img, 'Parent', app.UIAxes);
            %app.UIAxes.Title.String = file;
            
            app.original = app.img;
        end
        
        function [] = saveFile(app)
            [filename, ext, user_canceled] = imputfile;
            if user_canceled
                return
            end
            imwrite(app.img, filename);
        end
        
        function [result] = RGBToGray(app, img, op, ratio)
            %1	R
            %2	G
            %3	B
            %4	Avg
            %5	Ratio
            [H, W, L] = size(img);
            
            if L ~= 3
                errordlg('The image is not RGB.','RGB Error');
                result = img;
                return;
            end
            
            r = img(:, :, 1);
            g = img(:, :, 2);
            b = img(:, :, 3);
            
            if op == 1
                result = r;
            elseif op == 2
                result = g;
            elseif op == 3
                result = b;
            elseif op == 4
                result = (r+g+b)./3;
            else
                result = r.*ratio(1) + g.*ratio(2) + b.*ratio(3);
            end
            
            result = min(round(result), 255);
        end
        
        function [] = update_image(app)
            imshow(app.img, 'Parent', app.UIAxes);
        end
    
        
        function result = get_color(app, I, c)
            [H, W, L] = size(I);
            
            if L ~= 3
                errordlg('The image is not RGB.','RGB Error');
                result = I;
                return;
            end
            
            dims = [1 2 3];
            I(:, :, dims(dims ~= c)) = 0;
            result = I;
        end
        
        function [result] = Gray2Binary(app, img, threshold)
            [row, col] = size(img);
            result = zeros(row, col);
            for ii=1:row
                for jj=1:col
                    if img(ii, jj) > threshold
                        result(ii, jj) = 1;
                    end
                end
            end
        end
        
        function [] = resize(app)
            drawnow;
            app.AGIPToolsUIFigure.WindowState = 'maximized';
            app.UIAxes.Visible = 0;
        end
        
        function [s] = Logarithm(app, img, c)
            r = im2double(img);
            s = c .* log2(1 + r);
            s = im2uint8(s);
        end
    
        function [s] = InverseLogarithm(app, img)
            r = im2double(img);
            s = power(2, r) - 1;
            s = im2uint8(s);
        end
    
        function [s] = Pow(app, img, y, c)
            r = im2double(img);
            s = c .* (r .^ y);
            s = im2uint8(s);
        end
    
        function [s] = Root(app, img, y)
            r = im2double(img);
            s = r .^ (1/y);
            s = im2uint8(s);
        end
    
        function [s] = Negative(app, img)
            r = img;
    	   s = 255 - r;
        end
    
        function [count] = Hist(app, img)
            [H, W, L] = size(img);
            colors = ['r', 'g', 'b'];
            
            for k=1:L
                count = zeros(256, 1);
                for i=1:H
                    for j=1:W
                        count(img(i, j, k)+1) = count(img(i, j, k)+1) + 1;
                    end
                end
                figure, bar(count, colors(k));
            end
        end
    
    
        function [count] = HistOne(app, img)
            [H, W, L] = size(img);
            res = zeros(256, 1);
            
            for k=1:L
                count = zeros(256, 1);
                for i=1:H
                    for j=1:W
                        count(img(i, j, k)+1) = count(img(i, j, k)+1) + 1;
                    end
                end
                res = res + count;
            end
            
            figure, bar(res);
        end
    
    
        function [s] = Adjust(app, img, newmin, newmax)
            [H, W, L] = size(img);
            s = zeros(H, W, L);
            
            newmin = double(newmin);
            newmax = double(newmax);
            
            for k=1:L
                oldmin = double(min(min(img(:, :, k))));
                oldmax = double(max(max(img(:, :, k))));
                
                r = double(img(:, :, k));
                s(:, :, k) = ((r - oldmin)./(oldmax-oldmin)).*(newmax-newmin) + newmin;
            end
            
            s = uint8(s);
        end
    
        function [result] = imArithmetic(app, img, offset, op)
            [row, col, d] = size(img);
            result = zeros(row, col, d);
            
            if op == '+'
                s = img + offset;
                result = min(s, 255);
            elseif op == '-'
                s = img - offset;
                result = max(s, 0);
            elseif op == '*'
                s = round(img .* offset);
                result = min(s, 255);
            elseif op == '/'
                s = round(img ./ offset);
                result = max(s, 0);
            end
        end

        
        function [results] = BitPlaneSlicing(app, I)
            [H W] = size(I);
            results = zeros(H, W, 8);
            for i=1:H
                for j=1:W
                    for k=1:8
                        results(i, j, k) = bitget(I(i, j), k, 'uint8');
                    end
                end
            end
        end
        
        function [result] = HistEq(app, img)
            [H, W] = size(img);
            count = zeros(256, 1);
            result = zeros(H, W);
            
            for i=1:H
                for j=1:W
                    count(img(i, j)+1) = count(img(i, j)+1) + 1;
                end
            end
            
            p = count / (H*W);
            cdf = zeros(256, 1);
            
            for i=1:256
                cdf(i) = p(i);
                if i ~= 1
                    cdf(i) = cdf(i) + cdf(i - 1);
                end
            end
            
            s = round(255*cdf);
            for i=1:H
                for j=1:W
                    result(i, j) = s(img(i, j) + 1);
                end
            end
            
            result = uint8(result);
        end
        
        function [result] = ImFilter(app, I, mask)
            [M, N] = size(mask);
            k = (M-1) ./ 2;
            im = double(padarray(I, [k, k]));
            
            [H, W, L] = size(im);
            result = zeros(H, W, L);
            
            for c=1:L
                for i=k+1:H-k
                    for j=k+1:W-k
                        subimg = im(i-k:i+k, j-k:j+k, c);
                        result(i, j, c) = sum(sum(subimg .* mask));
                                
                        if result(i, j, c) > 255
                            result(i, j, c) = 255;
                        elseif result(i, j, c) < 0
                            result(i, j, c) = 0;
                        end
                    end
                end
            end
            
            result = result(k+1:H-k, k+1:W-k, :);
            result = uint8(result);
        end
       
    
        function [result] = NonLinearImFilter(app, I, mask, op)
            M = mask;
            k = (M-1) ./ 2;
            im = double(padarray(I, [k, k]));
            
            [H, W, L] = size(im);
            result = zeros(H, W, L);
            
            for c=1:L
                for i=k+1:H-k
                    for j=k+1:W-k
                        subimg = im(i-k:i+k, j-k:j+k, c);
                        sorted = sort(reshape(subimg, [1, M.*M]));
                        
                        if op==1
                            result(i, j, c) = min(sorted);
                        elseif op==2
                            result(i, j, c) = max(sorted);
                        elseif op==3
                            result(i, j, c) = median(sorted);
                        elseif op==4
                            result(i, j, c) = (min(sorted) + max(sorted)) ./ 2;
                        end
                                
                        if result(i, j, c) > 255
                            result(i, j, c) = 255;
                        elseif result(i, j, c) < 0
                            result(i, j, c) = 0;
                        end
                    end
                end
            end
            
            result = result(k+1:H-k, k+1:W-k, :);
            result = uint8(result);
        end


        function [result] = MeanFilter(app, I, mask, op)
            M = mask;
            k = (M-1) ./ 2;
            im = double(padarray(I, [k, k], 1));
            
            [H, W, L] = size(im);
            result = zeros(H, W, L);
            
            for c=1:L
                for i=k+1:H-k
                    for j=k+1:W-k
                        subimg = im(i-k:i+k, j-k:j+k, c);

                        if op==1
                            val = prod(prod(subimg));
                            result(i, j, c) = round(power(val, 1/(M*M)));
                            
                        elseif op==2
                            val = sum(sum(1 ./ subimg));
                            result(i, j, c) = round((M*M) / val);
                        end
                                
                        if result(i, j, c) > 255
                            result(i, j, c) = 255;
                        elseif result(i, j, c) < 0
                            result(i, j, c) = 0;
                        end
                    end
                end
            end
            
            result = result(k+1:H-k, k+1:W-k, :);
            result = uint8(result);
        end
    
    
        function result = IdealFilter(app, im, D0, idx, flag)
            im = double(im);
            [H, W] = size(im);
            filter = double(zeros(H, W));
            
            for i=1:H
                for j=1:W
                    D = sqrt((i - H/2).^2 + (j - W/2).^2);
                    
                    if D <= D0
                        filter(i, j) = 1;
                    end
                end
            end
            
            if idx == 1
                filter = 1 - filter;
            end
            
            % DFT
            f = fft2(im);
            f = fftshift(f);
            
            % Applying the filter
            result = filter .* f;
            
            % IDFT
            result = fftshift(result);
            result = ifft2(result);
            result = abs(result);
            %result = mat2gray(result);
            result = uint8(result);
            
            if flag
                figure, imshow(filter);
            end
        end


        function result = GaussianFilter(app, im, D0, idx, flag)
            [H, W] = size(im);
            filter = zeros(H, W);
            
            for i=1:H
                for j=1:W
                    D = sqrt((i - H/2).^2 + (j - W/2).^2);
                    filter(i, j) = exp((-D.*D) / (2*D0.*D0));
                end
            end
           
            if idx == 1
                filter = 1 - filter;
            end
            
            % DFT
            f = fft2(im);
            f = fftshift(f);
            
            % Applying the filter
            result = filter .* f;
            
            % IDFT
            result = fftshift(result);
            result = ifft2(result);
            result = abs(result);
            %result = mat2gray(result);
            result = uint8(result);
            
            if flag
                figure, imshow(filter);
            end
        end
    
    
        function result = ButterworthFilter(app, im, D0, n, idx, flag)
            [H, W, L] = size(im);
            filter = zeros(H, W, L);
            
            for k=1:L
                for i=1:H
                    for j=1:W
                        D = sqrt((i - H/2).^2 + (j - W/2).^2);
                        filter(i, j, k) = 1.0 ./ (1 + power(D ./ D0, 2*n));
                    end
                end
            end
            
            if idx == 1
                filter = 1 - filter;
            end
            
            % DFT
            f = fft2(im);
            f = fftshift(f);
            
            % Applying the filter
            result = filter .* f;
            
            % IDFT
            result = fftshift(result);
            result = ifft2(result);
            result = abs(result);
            %result = mat2gray(result);
            result = uint8(result);
            
            if flag
                figure, imshow(filter);
            end
        end


        function result = BandIdealFilter(app, im, D0, A, idx, flag)
            [H, W, L] = size(im);
            filter = zeros(H, W, L);
            
            for k=1:L
                for i=1:H
                    for j=1:W
                        D = sqrt((i - H/2).^2 + (j - W/2).^2);
                        
                        if (D >= (D0 - A/2)) && (D <= (D0 + A/2))
                            filter(i, j, k) = 1;
                        end
                    end
                end
            end
            
            if idx == 1
                filter = 1 - filter;
            end
            
            % DFT
            f = fft2(im);
            f = fftshift(f);
            
            % Applying the filter
            result = filter .* f;
            
            % IDFT
            result = fftshift(result);
            result = ifft2(result);
            result = abs(result);
            %result = mat2gray(result);
            result = uint8(result);
            
            if flag
                figure, imshow(filter);
            end
        end


        function result = BandGaussianFilter(app, im, D0, A, idx, flag)
            [H, W, L] = size(im);
            filter = zeros(H, W, L);
            
            for k=1:L
                for i=1:H
                    for j=1:W
                        D = sqrt((i - H/2).^2 + (j - W/2).^2);
                        filter(i, j, k) = exp(-power((power(D, 2) - power(D0, 2)) / (D.*A), 2));
                    end
                end
            end
            
            if idx == 1
                filter = 1 - filter;
            end
            
            % DFT
            f = fft2(im);
            f = fftshift(f);
            
            % Applying the filter
            result = filter .* f;
            
            % IDFT
            result = fftshift(result);
            result = ifft2(result);
            result = abs(result);
            %result = mat2gray(result);
            result = uint8(result);
            
            if flag
                figure, imshow(filter);
            end
        end
    
    
        function result = BandButterworthFilter(app, im, D0, A, n, idx, flag)
            [H, W, L] = size(im);
            filter = zeros(H, W, L);
            
            for k=1:L
                for i=1:H
                    for j=1:W
                        D = sqrt((i - H/2).^2 + (j - W/2).^2);
                        filter(i, j, k) = 1 - (1.0 ./ (1 + power((D.*A) / (power(D, 2) - power(D0, 2)), 2*n)));
                    end
                end
            end
            
            if idx == 1
                filter = 1 - filter;
            end
            
            % DFT
            f = fft2(im);
            f = fftshift(f);
            
            % Applying the filter
            result = filter .* f;
            
            % IDFT
            result = fftshift(result);
            result = ifft2(result);
            result = abs(result);
            %result = mat2gray(result);
            result = uint8(result);
            
            if flag
                figure, imshow(filter);
            end
        end
    
    
        function result = SaltPepperNoise(app, im, Ps, Pp)
            [H, W, L] = size(im);
            
            numOfSalt = round(Ps * W * H);
            numOfPepper = round(Pp * W * H);
            
            for k=1:L
                for i=1:numOfSalt
                    r = ceil(rand(1, 1) * H);
                    c = ceil(rand(1, 1) * W);
                    im(r, c, k) = 255;
                end
                
                for i=1:numOfPepper
                    r = ceil(rand(1, 1) * H);
                    c = ceil(rand(1, 1) * W);
                    im(r, c, k) = 0;
                end
            end
            
            result = uint8(im);
        end
    
    
        function result = GaussianNoise(app, im, m, s)
            im = double(im);
            [H, W, L] = size(im);
            
            for k=1:L
                for z=1:255
                    numOfNoise = round((1/(sqrt(2*pi)*s)) * exp(-power(z-m, 2) / (2*s*s)) * W * H);
                    
                    for i=1:numOfNoise
                        r = ceil(rand(1, 1) * H);
                        c = ceil(rand(1, 1) * W);
                        im(r, c, k) = im(r, c, k) + z;
                    end
                end
            end
            
            result = Adjust(app, im, 0, 255);
        end
    
    
        function result = UniformNoise(app, im, a, b)
            im = double(im);
            [H, W, L] = size(im);
            
            numOfNoise = round((1.0 / (b-a)) * W * H) ;
            
            for k=1:L
                for z=a:b
                    for i=1:numOfNoise
                        r = ceil(rand(1, 1) * H);
                        c = ceil(rand(1, 1) * W);
                        im(r, c, k) = im(r, c, k) + z;
                    end
                end
            end
            
            result = Adjust(app, im, 0, 255);
        end
    
    
        function result = ExpNoise(app, im, a)
            im = double(im);
            [H, W, L] = size(im);
            
            for k=1:L
                for z=1:255
                    numOfNoise = round(a*exp(-a*z) * W * H) ;
                    for i=1:numOfNoise
                        r = ceil(rand(1, 1) * H);
                        c = ceil(rand(1, 1) * W);
                        im(r, c, k) = im(r, c, k) + z;
                    end
                end
            end
            
            result = Adjust(app, im, 0, 255);
        end
    
    
        function result = RayleighNoise(app, im, a, b)
            im = double(im);
            [H, W, L] = size(im);
            
            for k=1:L
                for z=1:255
                    numOfNoise = round((2/b) * (z-a) * exp(-power(z-a, 2) / b) * W * H) ;
                    
                    for i=1:numOfNoise
                        r = ceil(rand(1, 1) * H);
                        c = ceil(rand(1, 1) * W);
                        im(r, c, k) = im(r, c, k) + z;
                    end
                end
            end
            
            result = Adjust(app, im, 0, 255);
        end
    
    
        function result = GammaNoise(app, im, a, b)
            im = double(im);
            [H, W, L] = size(im);
            
            for k=1:L
                for z=1:255
                    numOfNoise = round(power(a, b) * power(z, b-1) * exp(-a*z) / factorial(b-1) * W * H) ;
                    
                    for i=1:numOfNoise
                        r = ceil(rand(1, 1) * H);
                        c = ceil(rand(1, 1) * W);
                        im(r, c, k) = im(r, c, k) + z;
                    end
                end
            end
            
            result = Adjust(app, im, 0, 255);
        end
    
    
        function [] = FT(app, im)
            f1 = fft2(im);
            f2 = fftshift(f1);
            a = abs(f2);
            a = log(1 + a);
            a = mat2gray(a);
            imshow(a)
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            resize(app);
            %
        end

        % Callback function
        function BrowseButtonPushed(app, event)
            browseFile(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            browseFile(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            saveFile(app)
        end

        % Menu selected function: RedMenu
        function RedMenuSelected(app, event)
            app.img = RGBToGray(app, app.img, 1);
            update_image(app);
        end

        % Menu selected function: GreenMenu
        function GreenMenuSelected(app, event)
            app.img = RGBToGray(app, app.img, 2);
            update_image(app);
        end

        % Menu selected function: BlueMenu
        function BlueMenuSelected(app, event)
            app.img = RGBToGray(app, app.img, 3);
            update_image(app);
        end

        % Menu selected function: AverageMenu
        function AverageMenuSelected(app, event)
            app.img = RGBToGray(app, app.img, 4);
            update_image(app);
        end

        % Menu selected function: RatioMenu
        function RatioMenuSelected(app, event)
            ratio = str2double(inputdlg({'R','G','B'},'RGB Ratio', [1 30; 1 30; 1 30]));
            
            if sum(ratio) == 1.0
                app.img = RGBToGray(app, app.img, 5, ratio);
                update_image(app);
            else
                errordlg('The sum of the ratio must be 1','Ratio Error');
            end
        end

        % Menu selected function: GrayToBinaryMenu
        function GrayToBinaryMenuSelected(app, event)
            threshold = str2double(inputdlg('Threshold','RGB Ratio', [1 30]));
            
            if threshold >= 0 && threshold <= 255
                app.img = Gray2Binary(app, app.img, threshold);
                update_image(app);
            else
                errordlg('The threshold is out of the range [0, 255]','Threshold Error');
            end
        end

        % Menu selected function: RedMenu_2
        function RedMenu_2Selected(app, event)
            app.img = get_color(app, app.img, 1);
            update_image(app);
        end

        % Menu selected function: GreenMenu_2
        function GreenMenu_2Selected(app, event)
            app.img = get_color(app, app.img, 2);
            update_image(app);
        end

        % Menu selected function: BlueMenu_2
        function BlueMenu_2Selected(app, event)
            app.img = get_color(app, app.img, 3);
            update_image(app);
        end

        % Menu selected function: LogarithmicMenu
        function LogarithmicMenuSelected(app, event)
            app.img = Logarithm(app, app.img, 1);
            update_image(app);
        end

        % Menu selected function: InverseLogMenu
        function InverseLogMenuSelected(app, event)
            app.img = InverseLogarithm(app, app.img);
            update_image(app);
        end

        % Menu selected function: PowerMenu
        function PowerMenuSelected(app, event)
            y = str2double(inputdlg('y','Gamma Value', [1 30]));
            app.img = Pow(app, app.img, y, 1);
            update_image(app);
        end

        % Menu selected function: RootMenu
        function RootMenuSelected(app, event)
            n = str2double(inputdlg('n','Root Value', [1 30]));
            app.img = Root(app, app.img, n);
            update_image(app);
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.img = Negative(app, app.img);
            update_image(app);
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            Hist(app, app.img);
        end

        % Menu selected function: ContrastStretchingMenu
        function ContrastStretchingMenuSelected(app, event)
            res = str2double(inputdlg({'Min','Max'},'Contrast Stretching', [1 30; 1 30]));
            newmin = res(1);
            newmax = res(2);
            
            if (newmin >= 0 && newmin <= 255) && (newmax >= 0 && newmax <= 255) && (newmax > newmin)
                app.img = Adjust(app, app.img, newmin, newmax);
                update_image(app);
            else
                errordlg('Out of range [0 255]');
            end
        end

        % Menu selected function: SumMenu
        function SumMenuSelected(app, event)
            offset = str2double(inputdlg('Value','Offset Value', [1 30]));
            app.img = imArithmetic(app, app.img, offset, '+');
            update_image(app);
        end

        % Menu selected function: MultiplicationMenu
        function MultiplicationMenuSelected(app, event)
            offset = str2double(inputdlg('Value','Offset Value', [1 30]));
            app.img = imArithmetic(app, app.img, offset, '*');
            update_image(app);
        end

        % Menu selected function: DifferenceMenu
        function DifferenceMenuSelected(app, event)
            offset = str2double(inputdlg('Value','Offset Value', [1 30]));
            app.img = imArithmetic(app, app.img, offset, '-');
            update_image(app);
        end

        % Menu selected function: DivisionMenu
        function DivisionMenuSelected(app, event)
            offset = str2double(inputdlg('Value','Offset Value', [1 30]));
            app.img = imArithmetic(app, app.img, offset, '/');
            update_image(app);
        end

        % Callback function
        function UndoMenuSelected(app, event)
%             undo_image(app);
        end

        % Menu selected function: BitPlaneSlicingMenu
        function BitPlaneSlicingMenuSelected(app, event)
            [H, W, L] = size(app.img);
            for k=1:L
                planes = BitPlaneSlicing(app, app.img(:, :, k));
                for i=1:8
                    figure, imshow(planes(:,:,i));
                end
            end
        end

        % Menu selected function: HistogramEqualizationMenu
        function HistogramEqualizationMenuSelected(app, event)
            [~, ~, L] = size(app.img);
            
            for k=1:L
                app.img(:, :, k) = HistEq(app, app.img(:, :, k));
            end
            
            Hist(app, app.img);
            update_image(app);
        end

        % Menu selected function: UserFilterMenu
        function UserFilterMenuSelected(app, event)
            n = str2double(inputdlg('Filter Size','Filter Size', [1 30]));
            if mod(n, 2) ==0
                errordlg('Filter size must be odd');
                return
            end
            
            global user_mask;
            user_mask = [];
            d = dialog('Position',[400 400 400 400],'Name','Filter');
            
            %t=uitable('Data',dat,'ColumnFormat',columnformat,'ColumnEditable',columneditable);
            uit = uitable(d, 'ColumnEditable', true);
            uit.Data = zeros(n);
            uit.Position = [50 50 300 300];  
            
            btn = uicontrol('Parent' ,d ,...
                   'Position', [170 20 70 25], ...
                   'String','Save', ...
                   'Callback', {@user_kernel, uit, d});
            
            % Wait for d to close before running to completion
            uiwait(d);
            
            if isempty(user_mask)
                return
            end
            
            app.img = ImFilter(app, app.img, user_mask);
            update_image(app);
        end

        % Menu selected function: SimpleLaplacianMenu_3
        function SimpleLaplacianMenuSelected(app, event)
            kernel = [0 1 0; 1 -4 1; 0 1 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: VariantofLaplacianMenu_3
        function VariantofLaplacianMenuSelected(app, event)
            kernel = [1 1 1; 1 -8 1; 1 1 1];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Callback function
        function KernelMenu_3Selected(app, event)
%             kernel = [0 -1 0; -1 4 -1; 0 -1 0];
%             app.img = ImFilter(app, app.img, kernel);
%             update_image(app);
        end

        % Callback function
        function KernelMenu_4Selected(app, event)
%             kernel = [-1 -1 -1; -1 8 -1; -1 -1 -1];
%             app.img = ImFilter(app, app.img, kernel);
%             update_image(app);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
% 
        end

        % Menu selected function: HorizontalMenu
        function HorizontalMenuSelected(app, event)
            kernel = [-1 -2 -1; 0 0 0; 1 2 1];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: VerticalMenu
        function VerticalMenuSelected(app, event)
            kernel = [-1 0 1; -2 0 2; -1 0 1];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: LeftDiagonalMenu
        function LeftDiagonalMenuSelected(app, event)
            kernel = [0 -1 -2; 1 0 -1; 2 1 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: RightDiagonalMenu
        function RightDiagonalMenuSelected(app, event)
            kernel = [2 1 0; 1 0 -1; 0 -1 -2];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: HorizontalMenu_2
        function HorizontalMenu_2Selected(app, event)
            kernel = [0 1 0; 0 0 0; 0 -1 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: VerticalMenu_2
        function VerticalMenu_2Selected(app, event)
            kernel = [0 0 0; 1 0 -1; 0 0 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: LeftDiagonalMenu_2
        function LeftDiagonalMenu_2Selected(app, event)
            kernel = [0 0 1; 0 0 0; -1 0 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: RightDiagonalMenu_2
        function RightDiagonalMenu_2Selected(app, event)
            kernel = [1 0 0; 0 0 0; 0 0 -1];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: LaplacianMenu_2
        function LaplacianMenu_2Selected(app, event)
            kernel = [0 -1 0; -1 5 -1; 0 -1 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: HorizontalMenu_3
        function HorizontalMenu_3Selected(app, event)
            kernel = [0 1 0; 0 1 0; 0 -1 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: VerticalMenu_3
        function VerticalMenu_3Selected(app, event)
            kernel = [0 0 0; 1 1 -1; 0 0 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: RightDiagonalMenu_3
        function RightDiagonalMenu_3Selected(app, event)
            kernel = [0 0 1; 0 1 0; -1 0 0];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: LeftDiagonalMenu_3
        function LeftDiagonalMenu_3Selected(app, event)
            kernel = [1 0 0; 0 1 0; 0 0 -1];
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Callback function
        function BoxFilterMenuSelected(app, event)
            c = str2double(inputdlg({'c'},'Box Filter', [1 30]));
            if isempty(c)
                return
            end
            kernel = ones(c) ./ power(c, 2);
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: MinMenu
        function MinMenuSelected(app, event)
            s = str2double(inputdlg('M','Filter Size', [1 30]));
            if isempty(s)
                return
            end
            
            app.img = NonLinearImFilter(app, app.img, s, 1);
            update_image(app);
        end

        % Menu selected function: MaxMenu
        function MaxMenuSelected(app, event)
            s = str2double(inputdlg('M','Filter Size', [1 30]));
            if isempty(s)
                return
            end
            
            app.img = NonLinearImFilter(app, app.img, s, 2);
            update_image(app);
        end

        % Menu selected function: MedianMenu
        function MedianMenuSelected(app, event)
            s = str2double(inputdlg('M','Filter Size', [1 30]));
            if isempty(s)
                return
            end
            
            app.img = NonLinearImFilter(app, app.img, s, 3);
            update_image(app);
        end

        % Menu selected function: MidpointMenu
        function MidpointMenuSelected(app, event)
            s = str2double(inputdlg('M','Filter Size', [1 30]));
            if isempty(s)
                return
            end
            
            app.img = NonLinearImFilter(app, app.img, s, 4);
            update_image(app);
        end

        % Menu selected function: IdealMenu_3
        function IdealMenu_3Selected(app, event)
            D0 = str2double(inputdlg('D0','D0', [1 30]));
            if isempty(D0)
                return
            end
            
            [H, W, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = IdealFilter(app, app.img(:, :, k), D0, 0, flag);
            end
            update_image(app);
        end

        % Menu selected function: IdealMenu_4
        function IdealMenu_4Selected(app, event)
            D0 = str2double(inputdlg('D0','D0', [1 30]));
            if isempty(D0)
                return
            end
            
            [H, W, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = IdealFilter(app, app.img(:, :, k), D0, 1, flag);
            end
            update_image(app);
        end

        % Menu selected function: GaussianMenu_3
        function GaussianMenu_3Selected(app, event)
            D0 = str2double(inputdlg('D0','D0', [1 30]));
            if isempty(D0)
                return
            end
            
            [H, W, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = GaussianFilter(app, app.img(:, :, k), D0, 0, flag);
            end
            update_image(app);
        end

        % Menu selected function: GaussianMenu_4
        function GaussianMenu_4Selected(app, event)
            D0 = str2double(inputdlg('D0','D0', [1 30]));
            if isempty(D0)
                return
            end
            
            [H, W, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = GaussianFilter(app, app.img(:, :, k), D0, 1, flag);
            end
            update_image(app);
        end

        % Menu selected function: ButterworthMenu_3
        function ButterworthMenu_3Selected(app, event)
            res = str2double(inputdlg({'D0','n'},'Butterworth', [1 30; 1 30]));
            D0 = res(1);
            n = res(2);
            
            if isempty(D0) || isempty(n)
                return
            end
            
            [H, W, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = ButterworthFilter(app, app.img(:, :, k), D0, n, 0, flag);
            end
            update_image(app);
        end

        % Menu selected function: ButterworthMenu_4
        function ButterworthMenu_4Selected(app, event)
            res = str2double(inputdlg({'D0','n'},'Butterworth', [1 30; 1 30]));
            D0 = res(1);
            n = res(2);
            
            if isempty(D0) || isempty(n)
                return
            end
            
            [H, W, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = ButterworthFilter(app, app.img(:, :, k), D0, n, 1, flag);
            end
            update_image(app);
        end

        % Menu selected function: IdealMenu_5
        function IdealMenu_5Selected(app, event)
            res = str2double(inputdlg({'D0','W'},'Band', [1 30; 1 30]));
            D0 = res(1);
            W = res(2);
            
            if isempty(D0) || isempty(W)
                return
            end
            
            [~, ~, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = BandIdealFilter(app, app.img(:, :, k), D0, W, 0, flag);
            end
            
            update_image(app);
        end

        % Menu selected function: IdealMenu_6
        function IdealMenu_6Selected(app, event)
            res = str2double(inputdlg({'D0','W'},'Band', [1 30; 1 30]));
            D0 = res(1);
            W = res(2);
            
            if isempty(D0) || isempty(W)
                return
            end
            
            [~, ~, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = BandIdealFilter(app, app.img(:, :, k), D0, W, 1, flag);
            end
            update_image(app);
        end

        % Menu selected function: GaussianMenu_5
        function GaussianMenu_5Selected(app, event)
            res = str2double(inputdlg({'D0','W'},'Band', [1 30; 1 30]));
            D0 = res(1);
            W = res(2);
            
            if isempty(D0) || isempty(W)
                return
            end
            
            [~, ~, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = BandGaussianFilter(app, app.img(:, :, k), D0, W, 0, flag);
            end

            update_image(app);
        end

        % Menu selected function: GaussianMenu_6
        function GaussianMenu_6Selected(app, event)
            res = str2double(inputdlg({'D0','W'},'Band', [1 30; 1 30]));
            D0 = res(1);
            W = res(2);
            
            if isempty(D0) || isempty(W)
                return
            end
            
            [~, ~, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = BandGaussianFilter(app, app.img(:, :, k), D0, W, 1, flag);
            end
            update_image(app);
        end

        % Menu selected function: ButterworthMenu_5
        function ButterworthMenu_5Selected(app, event)
            res = str2double(inputdlg({'D0','W', 'n'},'Band', [1 30; 1 30; 1 30]));
            D0 = res(1);
            W = res(2);
            n = res(3);
            
            if isempty(D0) || isempty(W) || isempty(n)
                return
            end
            
            [~, ~, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = BandButterworthFilter(app, app.img(:, :, k), D0, W, n, 0, flag);
            end
            
            update_image(app);
        end

        % Menu selected function: BandrejectFiltersMenu
        function BandrejectFiltersMenuSelected(app, event)
            
        end

        % Menu selected function: SaltandPepperMenu
        function SaltandPepperMenuSelected(app, event)
            res = str2double(inputdlg({'Ps','Pp'},'Noise', [1 30; 1 30]));
            Ps = res(1);
            Pp = res(2);
            
            if isempty(Ps) || isempty(Pp)
                return
            end
            
            app.img = SaltPepperNoise(app, app.img, Ps, Pp);
            update_image(app);
        end

        % Menu selected function: GaussianMenu_7
        function GaussianMenu_7Selected(app, event)
            res = str2double(inputdlg({'m','s'},'Noise', [1 30; 1 30]));
            m = res(1);
            s = res(2);
            
            if isempty(m) || isempty(s)
                return
            end
            
            app.img = GaussianNoise(app, app.img, m, s);
            update_image(app);
        end

        % Menu selected function: UniformMenu
        function UniformMenuSelected(app, event)
            res = str2double(inputdlg({'a','b'},'Noise', [1 30; 1 30]));
            a = res(1);
            b = res(2);
            
            if isempty(a) || isempty(b)
                return
            end
            
            app.img = UniformNoise(app, app.img, a, b);
            update_image(app);
        end

        % Menu selected function: ExponentialMenu
        function ExponentialMenuSelected(app, event)
            res = str2double(inputdlg({'a'},'Noise', [1 30]));
            a = res(1);
            
            if isempty(a)
                return
            end
            
            app.img = ExpNoise(app, app.img, a);
            update_image(app);
        end

        % Menu selected function: RayleighMenu
        function RayleighMenuSelected(app, event)
            res = str2double(inputdlg({'a','b'},'Noise', [1 30; 1 30]));
            a = res(1);
            b = res(2);
            
            if isempty(a) || isempty(b)
                return
            end
            
            app.img = RayleighNoise(app, app.img, a, b);
            update_image(app);
        end

        % Menu selected function: GammaMenu
        function GammaMenuSelected(app, event)
            res = str2double(inputdlg({'a','b'},'Noise', [1 30; 1 30]));
            a = res(1);
            b = res(2);
            
            if isempty(a) || isempty(b)
                return
            end
            
            app.img = GammaNoise(app, app.img, a, b);
            update_image(app);
        end

        % Menu selected function: ArithmeticMeanMenu_2
        function ArithmeticMeanMenu_2Selected(app, event)
            c = str2double(inputdlg({'c'},'Box Filter', [1 30]));
            if isempty(c)
                return
            end
            kernel = ones(c) ./ power(c, 2);
            app.img = ImFilter(app, app.img, kernel);
            update_image(app);
        end

        % Menu selected function: GeometricMeanMenu
        function GeometricMeanMenuSelected(app, event)
            c = str2double(inputdlg({'c'},'Box Filter', [1 30]));
            if isempty(c)
                return
            end

            app.img = MeanFilter(app, app.img, c, 1);
            update_image(app);
        end

        % Menu selected function: HarmonicMeanMenu
        function HarmonicMeanMenuSelected(app, event)
            c = str2double(inputdlg({'c'},'Box Filter', [1 30]));
            if isempty(c)
                return
            end

            app.img = MeanFilter(app, app.img, c, 2);
            update_image(app);
        end

        % Menu selected function: ButterworthMenu_6
        function ButterworthMenu_6Selected(app, event)
            res = str2double(inputdlg({'D0','W', 'n'},'Band', [1 30; 1 30; 1 30]));
            D0 = res(1);
            W = res(2);
            n = res(3);
            
            if isempty(D0) || isempty(W) || isempty(n)
                return
            end
            
            [~, ~, L] = size(app.img);
            flag = false;
            for k=1:L
                
                if k==L
                    flag = true;
                end
                
                app.img(:, :, k) = BandButterworthFilter(app, app.img(:, :, k), D0, W, n, 1, flag);
            end
            update_image(app);
        end

        % Menu selected function: LogDFTMenu
        function LogDFTMenuSelected(app, event)
            FT(app, app.img);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            app.img = app.original;
            update_image(app);
        end

        % Menu selected function: OneHistogramMenu
        function OneHistogramMenuSelected(app, event)
            HistOne(app, app.img);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AGIPToolsUIFigure = uifigure;
            app.AGIPToolsUIFigure.Name = 'AGIPTools';
            app.FileMenu = uimenu(app.AGIPToolsUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ResetMenu = uimenu(app.FileMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.ImageMenu = uimenu(app.AGIPToolsUIFigure);
            app.ImageMenu.Text = 'Image';
            app.ColorsMenu = uimenu(app.ImageMenu);
            app.ColorsMenu.Text = 'Colors';
            app.RedMenu_2 = uimenu(app.ColorsMenu);
            app.RedMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RedMenu_2Selected, true);
            app.RedMenu_2.Text = 'Red';
            app.GreenMenu_2 = uimenu(app.ColorsMenu);
            app.GreenMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GreenMenu_2Selected, true);
            app.GreenMenu_2.Text = 'Green';
            app.BlueMenu_2 = uimenu(app.ColorsMenu);
            app.BlueMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BlueMenu_2Selected, true);
            app.BlueMenu_2.Text = 'Blue';
            app.RGBToGrayMenu = uimenu(app.ImageMenu);
            app.RGBToGrayMenu.Text = 'RGB To Gray';
            app.RedMenu = uimenu(app.RGBToGrayMenu);
            app.RedMenu.MenuSelectedFcn = createCallbackFcn(app, @RedMenuSelected, true);
            app.RedMenu.Text = 'Red';
            app.GreenMenu = uimenu(app.RGBToGrayMenu);
            app.GreenMenu.MenuSelectedFcn = createCallbackFcn(app, @GreenMenuSelected, true);
            app.GreenMenu.Text = 'Green';
            app.BlueMenu = uimenu(app.RGBToGrayMenu);
            app.BlueMenu.MenuSelectedFcn = createCallbackFcn(app, @BlueMenuSelected, true);
            app.BlueMenu.Text = 'Blue';
            app.AverageMenu = uimenu(app.RGBToGrayMenu);
            app.AverageMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageMenuSelected, true);
            app.AverageMenu.Text = 'Average';
            app.RatioMenu = uimenu(app.RGBToGrayMenu);
            app.RatioMenu.MenuSelectedFcn = createCallbackFcn(app, @RatioMenuSelected, true);
            app.RatioMenu.Text = 'Ratio';
            app.GrayToBinaryMenu = uimenu(app.ImageMenu);
            app.GrayToBinaryMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayToBinaryMenuSelected, true);
            app.GrayToBinaryMenu.Text = 'Gray To Binary';
            app.ArithmeticMenu = uimenu(app.ImageMenu);
            app.ArithmeticMenu.Text = 'Arithmetic';
            app.SumMenu = uimenu(app.ArithmeticMenu);
            app.SumMenu.MenuSelectedFcn = createCallbackFcn(app, @SumMenuSelected, true);
            app.SumMenu.Text = 'Sum';
            app.MultiplicationMenu = uimenu(app.ArithmeticMenu);
            app.MultiplicationMenu.MenuSelectedFcn = createCallbackFcn(app, @MultiplicationMenuSelected, true);
            app.MultiplicationMenu.Text = 'Multiplication';
            app.DifferenceMenu = uimenu(app.ArithmeticMenu);
            app.DifferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferenceMenuSelected, true);
            app.DifferenceMenu.Text = 'Difference';
            app.DivisionMenu = uimenu(app.ArithmeticMenu);
            app.DivisionMenu.MenuSelectedFcn = createCallbackFcn(app, @DivisionMenuSelected, true);
            app.DivisionMenu.Text = 'Division';
            app.IntensityTransformationsMenu = uimenu(app.ImageMenu);
            app.IntensityTransformationsMenu.Text = 'Intensity Transformations';
            app.LogarithmicMenu = uimenu(app.IntensityTransformationsMenu);
            app.LogarithmicMenu.MenuSelectedFcn = createCallbackFcn(app, @LogarithmicMenuSelected, true);
            app.LogarithmicMenu.Text = 'Logarithmic';
            app.InverseLogMenu = uimenu(app.IntensityTransformationsMenu);
            app.InverseLogMenu.MenuSelectedFcn = createCallbackFcn(app, @InverseLogMenuSelected, true);
            app.InverseLogMenu.Text = 'Inverse Log';
            app.PowerMenu = uimenu(app.IntensityTransformationsMenu);
            app.PowerMenu.MenuSelectedFcn = createCallbackFcn(app, @PowerMenuSelected, true);
            app.PowerMenu.Text = 'Power';
            app.RootMenu = uimenu(app.IntensityTransformationsMenu);
            app.RootMenu.MenuSelectedFcn = createCallbackFcn(app, @RootMenuSelected, true);
            app.RootMenu.Text = 'Root';
            app.NegativeMenu = uimenu(app.IntensityTransformationsMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.ContrastStretchingMenu = uimenu(app.ImageMenu);
            app.ContrastStretchingMenu.MenuSelectedFcn = createCallbackFcn(app, @ContrastStretchingMenuSelected, true);
            app.ContrastStretchingMenu.Text = 'Contrast Stretching';
            app.BitPlaneSlicingMenu = uimenu(app.ImageMenu);
            app.BitPlaneSlicingMenu.MenuSelectedFcn = createCallbackFcn(app, @BitPlaneSlicingMenuSelected, true);
            app.BitPlaneSlicingMenu.Text = 'Bit Plane Slicing';
            app.HistogramEqualizationMenu = uimenu(app.ImageMenu);
            app.HistogramEqualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramEqualizationMenuSelected, true);
            app.HistogramEqualizationMenu.Text = 'Histogram Equalization';
            app.FiltersMenu = uimenu(app.AGIPToolsUIFigure);
            app.FiltersMenu.Text = 'Filters';
            app.UserFilterMenu = uimenu(app.FiltersMenu);
            app.UserFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @UserFilterMenuSelected, true);
            app.UserFilterMenu.Text = 'User Filter';
            app.LinearSpatialFilteringMenu = uimenu(app.FiltersMenu);
            app.LinearSpatialFilteringMenu.Text = 'Linear Spatial Filtering';
            app.MeanMenu = uimenu(app.LinearSpatialFilteringMenu);
            app.MeanMenu.Text = 'Mean';
            app.ArithmeticMeanMenu_2 = uimenu(app.MeanMenu);
            app.ArithmeticMeanMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ArithmeticMeanMenu_2Selected, true);
            app.ArithmeticMeanMenu_2.Text = 'Arithmetic Mean';
            app.GeometricMeanMenu = uimenu(app.MeanMenu);
            app.GeometricMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @GeometricMeanMenuSelected, true);
            app.GeometricMeanMenu.Text = 'Geometric Mean';
            app.HarmonicMeanMenu = uimenu(app.MeanMenu);
            app.HarmonicMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @HarmonicMeanMenuSelected, true);
            app.HarmonicMeanMenu.Text = 'Harmonic Mean';
            app.EdgeDetectionMenu = uimenu(app.LinearSpatialFilteringMenu);
            app.EdgeDetectionMenu.Text = 'Edge Detection';
            app.LaplacianMenu = uimenu(app.EdgeDetectionMenu);
            app.LaplacianMenu.Text = 'Laplacian';
            app.SimpleLaplacianMenu_3 = uimenu(app.LaplacianMenu);
            app.SimpleLaplacianMenu_3.MenuSelectedFcn = createCallbackFcn(app, @SimpleLaplacianMenuSelected, true);
            app.SimpleLaplacianMenu_3.Text = 'Simple Laplacian';
            app.VariantofLaplacianMenu_3 = uimenu(app.LaplacianMenu);
            app.VariantofLaplacianMenu_3.MenuSelectedFcn = createCallbackFcn(app, @VariantofLaplacianMenuSelected, true);
            app.VariantofLaplacianMenu_3.Text = 'Variant of Laplacian';
            app.SobelMenu = uimenu(app.EdgeDetectionMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.HorizontalMenu = uimenu(app.SobelMenu);
            app.HorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @HorizontalMenuSelected, true);
            app.HorizontalMenu.Text = 'Horizontal';
            app.VerticalMenu = uimenu(app.SobelMenu);
            app.VerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalMenuSelected, true);
            app.VerticalMenu.Text = 'Vertical';
            app.LeftDiagonalMenu = uimenu(app.SobelMenu);
            app.LeftDiagonalMenu.MenuSelectedFcn = createCallbackFcn(app, @LeftDiagonalMenuSelected, true);
            app.LeftDiagonalMenu.Text = 'Left Diagonal';
            app.RightDiagonalMenu = uimenu(app.SobelMenu);
            app.RightDiagonalMenu.MenuSelectedFcn = createCallbackFcn(app, @RightDiagonalMenuSelected, true);
            app.RightDiagonalMenu.Text = 'Right Diagonal';
            app.RobertMenu = uimenu(app.EdgeDetectionMenu);
            app.RobertMenu.Text = 'Robert';
            app.HorizontalMenu_2 = uimenu(app.RobertMenu);
            app.HorizontalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HorizontalMenu_2Selected, true);
            app.HorizontalMenu_2.Text = 'Horizontal';
            app.VerticalMenu_2 = uimenu(app.RobertMenu);
            app.VerticalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @VerticalMenu_2Selected, true);
            app.VerticalMenu_2.Text = 'Vertical ';
            app.LeftDiagonalMenu_2 = uimenu(app.RobertMenu);
            app.LeftDiagonalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LeftDiagonalMenu_2Selected, true);
            app.LeftDiagonalMenu_2.Text = 'Left Diagonal';
            app.RightDiagonalMenu_2 = uimenu(app.RobertMenu);
            app.RightDiagonalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RightDiagonalMenu_2Selected, true);
            app.RightDiagonalMenu_2.Text = 'Right Diagonal';
            app.SharpeningMenu = uimenu(app.LinearSpatialFilteringMenu);
            app.SharpeningMenu.Text = 'Sharpening';
            app.LaplacianMenu_2 = uimenu(app.SharpeningMenu);
            app.LaplacianMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LaplacianMenu_2Selected, true);
            app.LaplacianMenu_2.Text = 'Laplacian';
            app.HorizontalMenu_3 = uimenu(app.SharpeningMenu);
            app.HorizontalMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HorizontalMenu_3Selected, true);
            app.HorizontalMenu_3.Text = 'Horizontal';
            app.VerticalMenu_3 = uimenu(app.SharpeningMenu);
            app.VerticalMenu_3.MenuSelectedFcn = createCallbackFcn(app, @VerticalMenu_3Selected, true);
            app.VerticalMenu_3.Text = 'Vertical';
            app.LeftDiagonalMenu_3 = uimenu(app.SharpeningMenu);
            app.LeftDiagonalMenu_3.MenuSelectedFcn = createCallbackFcn(app, @LeftDiagonalMenu_3Selected, true);
            app.LeftDiagonalMenu_3.Text = 'Left Diagonal';
            app.RightDiagonalMenu_3 = uimenu(app.SharpeningMenu);
            app.RightDiagonalMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RightDiagonalMenu_3Selected, true);
            app.RightDiagonalMenu_3.Text = 'Right Diagonal';
            app.NonlinearSpatialFilteringMenu = uimenu(app.FiltersMenu);
            app.NonlinearSpatialFilteringMenu.Text = 'Nonlinear Spatial Filtering';
            app.MinMenu = uimenu(app.NonlinearSpatialFilteringMenu);
            app.MinMenu.MenuSelectedFcn = createCallbackFcn(app, @MinMenuSelected, true);
            app.MinMenu.Text = 'Min';
            app.MaxMenu = uimenu(app.NonlinearSpatialFilteringMenu);
            app.MaxMenu.MenuSelectedFcn = createCallbackFcn(app, @MaxMenuSelected, true);
            app.MaxMenu.Text = 'Max';
            app.MedianMenu = uimenu(app.NonlinearSpatialFilteringMenu);
            app.MedianMenu.MenuSelectedFcn = createCallbackFcn(app, @MedianMenuSelected, true);
            app.MedianMenu.Text = 'Median';
            app.MidpointMenu = uimenu(app.NonlinearSpatialFilteringMenu);
            app.MidpointMenu.MenuSelectedFcn = createCallbackFcn(app, @MidpointMenuSelected, true);
            app.MidpointMenu.Text = 'Midpoint';
            app.FrequencyDomainMenu_2 = uimenu(app.AGIPToolsUIFigure);
            app.FrequencyDomainMenu_2.Text = 'Frequency Domain';
            app.LogDFTMenu = uimenu(app.FrequencyDomainMenu_2);
            app.LogDFTMenu.MenuSelectedFcn = createCallbackFcn(app, @LogDFTMenuSelected, true);
            app.LogDFTMenu.Text = 'Log DFT';
            app.LowpassFiltersMenu_2 = uimenu(app.FrequencyDomainMenu_2);
            app.LowpassFiltersMenu_2.Text = 'Lowpass Filters';
            app.IdealMenu_3 = uimenu(app.LowpassFiltersMenu_2);
            app.IdealMenu_3.MenuSelectedFcn = createCallbackFcn(app, @IdealMenu_3Selected, true);
            app.IdealMenu_3.Text = 'Ideal';
            app.GaussianMenu_3 = uimenu(app.LowpassFiltersMenu_2);
            app.GaussianMenu_3.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenu_3Selected, true);
            app.GaussianMenu_3.Text = 'Gaussian';
            app.ButterworthMenu_3 = uimenu(app.LowpassFiltersMenu_2);
            app.ButterworthMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ButterworthMenu_3Selected, true);
            app.ButterworthMenu_3.Text = 'Butterworth';
            app.HighpassFiltersMenu_2 = uimenu(app.FrequencyDomainMenu_2);
            app.HighpassFiltersMenu_2.Text = 'Highpass Filters';
            app.IdealMenu_4 = uimenu(app.HighpassFiltersMenu_2);
            app.IdealMenu_4.MenuSelectedFcn = createCallbackFcn(app, @IdealMenu_4Selected, true);
            app.IdealMenu_4.Text = 'Ideal';
            app.GaussianMenu_4 = uimenu(app.HighpassFiltersMenu_2);
            app.GaussianMenu_4.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenu_4Selected, true);
            app.GaussianMenu_4.Text = 'Gaussian';
            app.ButterworthMenu_4 = uimenu(app.HighpassFiltersMenu_2);
            app.ButterworthMenu_4.MenuSelectedFcn = createCallbackFcn(app, @ButterworthMenu_4Selected, true);
            app.ButterworthMenu_4.Text = 'Butterworth';
            app.BandpassFiltersMenu = uimenu(app.FrequencyDomainMenu_2);
            app.BandpassFiltersMenu.Text = 'Bandpass Filters';
            app.IdealMenu_5 = uimenu(app.BandpassFiltersMenu);
            app.IdealMenu_5.MenuSelectedFcn = createCallbackFcn(app, @IdealMenu_5Selected, true);
            app.IdealMenu_5.Text = 'Ideal';
            app.GaussianMenu_5 = uimenu(app.BandpassFiltersMenu);
            app.GaussianMenu_5.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenu_5Selected, true);
            app.GaussianMenu_5.Text = 'Gaussian';
            app.ButterworthMenu_5 = uimenu(app.BandpassFiltersMenu);
            app.ButterworthMenu_5.MenuSelectedFcn = createCallbackFcn(app, @ButterworthMenu_5Selected, true);
            app.ButterworthMenu_5.Text = 'Butterworth';
            app.BandrejectFiltersMenu = uimenu(app.FrequencyDomainMenu_2);
            app.BandrejectFiltersMenu.MenuSelectedFcn = createCallbackFcn(app, @BandrejectFiltersMenuSelected, true);
            app.BandrejectFiltersMenu.Text = 'Bandreject Filters';
            app.IdealMenu_6 = uimenu(app.BandrejectFiltersMenu);
            app.IdealMenu_6.MenuSelectedFcn = createCallbackFcn(app, @IdealMenu_6Selected, true);
            app.IdealMenu_6.Text = 'Ideal';
            app.GaussianMenu_6 = uimenu(app.BandrejectFiltersMenu);
            app.GaussianMenu_6.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenu_6Selected, true);
            app.GaussianMenu_6.Text = 'Gaussian';
            app.ButterworthMenu_6 = uimenu(app.BandrejectFiltersMenu);
            app.ButterworthMenu_6.MenuSelectedFcn = createCallbackFcn(app, @ButterworthMenu_6Selected, true);
            app.ButterworthMenu_6.Text = 'Butterworth';
            app.NoiseMenu = uimenu(app.AGIPToolsUIFigure);
            app.NoiseMenu.Text = 'Noise';
            app.SaltandPepperMenu = uimenu(app.NoiseMenu);
            app.SaltandPepperMenu.MenuSelectedFcn = createCallbackFcn(app, @SaltandPepperMenuSelected, true);
            app.SaltandPepperMenu.Text = 'Salt and Pepper';
            app.UniformMenu = uimenu(app.NoiseMenu);
            app.UniformMenu.MenuSelectedFcn = createCallbackFcn(app, @UniformMenuSelected, true);
            app.UniformMenu.Text = 'Uniform';
            app.GaussianMenu_7 = uimenu(app.NoiseMenu);
            app.GaussianMenu_7.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenu_7Selected, true);
            app.GaussianMenu_7.Text = 'Gaussian';
            app.RayleighMenu = uimenu(app.NoiseMenu);
            app.RayleighMenu.MenuSelectedFcn = createCallbackFcn(app, @RayleighMenuSelected, true);
            app.RayleighMenu.Text = 'Rayleigh';
            app.ExponentialMenu = uimenu(app.NoiseMenu);
            app.ExponentialMenu.MenuSelectedFcn = createCallbackFcn(app, @ExponentialMenuSelected, true);
            app.ExponentialMenu.Text = 'Exponential';
            app.GammaMenu = uimenu(app.NoiseMenu);
            app.GammaMenu.MenuSelectedFcn = createCallbackFcn(app, @GammaMenuSelected, true);
            app.GammaMenu.Text = 'Gamma';
            app.StatisticsMenu = uimenu(app.AGIPToolsUIFigure);
            app.StatisticsMenu.Text = 'Statistics';
            app.HistogramMenu = uimenu(app.StatisticsMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.OneHistogramMenu = uimenu(app.StatisticsMenu);
            app.OneHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @OneHistogramMenuSelected, true);
            app.OneHistogramMenu.Text = 'One Histogram';
            app.UIAxes = uiaxes(app.AGIPToolsUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = dip

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AGIPToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AGIPToolsUIFigure)
        end
    end
end